classdef QuailKit_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Fig         matlab.ui.Figure
        Group45     matlab.ui.container.Panel
        Five        matlab.ui.container.ButtonGroup
        Five_Slide  matlab.ui.control.Slider
        Five_List   matlab.ui.control.ListBox
        Five_Pop    matlab.ui.control.DropDown
        Four        matlab.ui.container.ButtonGroup
        Four_List   matlab.ui.control.ListBox
        Four_Slide  matlab.ui.control.Slider
        Four_Pop    matlab.ui.control.DropDown
        Group123    matlab.ui.container.Panel
        One         matlab.ui.container.ButtonGroup
        One_List    matlab.ui.control.ListBox
        One_Pop     matlab.ui.control.DropDown
        Two         matlab.ui.container.ButtonGroup
        Two_List    matlab.ui.control.ListBox
        Two_Slide   matlab.ui.control.Slider
        Two_Pop     matlab.ui.control.DropDown
        GroupPlay   matlab.ui.container.Panel
        Previous    matlab.ui.control.Button
        Back        matlab.ui.control.Button
        Clear       matlab.ui.control.Button
        Reset       matlab.ui.control.Button
        Skip        matlab.ui.control.Button
        Next        matlab.ui.control.Button
        QueueMode   matlab.ui.control.StateButton
        PlayMode    matlab.ui.control.Button
        Mode        matlab.ui.control.Button
        Debug       matlab.ui.control.Button
        Play        matlab.ui.control.Button
        Panel       matlab.ui.container.Panel
    end

    
    properties (Access = private)
        Data % Recording raw and process data
        Path % Description
    end
    
    methods (Access = private)
        function p = Credits(app, h, color, initialize)
            a=0.98;
            b=0.98;
            set(h,...
                'Position',[1,1,h.Position([3,4])],...
                'XLim',[1,h.Position(3)],...
                'YLim',[1,h.Position(4)]);
            s=round(h.Position(3:4));
            
            alpha=a*ones(2*s(2)+1,s(1));
            cdata=uint8(ones(2*s(2)+1,s(1),3));
            logo=h.Parent.Parent.UserData.HT_Big;
            logo=imresize(logo,0.2*min(s)/ceil(max(size(logo))),'nearest');
            logo=flipud(logo);
            m=size(logo(:,:,1));
            corner=round((flip(s)-size(logo(:,:,1)))/2+[0.33*s(2),0]);
            alpha(1+corner(1):m(1)+corner(1),1+corner(2):m(2)+corner(2))=1;
            cdata(1+corner(1):m(1)+corner(1),1+corner(2):m(2)+corner(2),:)=...
                repmat(logo(:,:,4),1,1,3);
            for i=1:3
                temp=uint8(cdata(:,:,i));
                temp(temp==1)=uint8(255*color(i)*temp(temp==1));
                temp(temp<1)=255;
                cdata(:,:,i)=temp;
            end
            logo=h.Parent.Parent.UserData.TTU_COA;
            logo=imresize(logo,0.5*min(s)/ceil(max(size(logo))),'bilinear');
            logo=flipud(logo);
            m=size(logo(:,:,1));
            corner=round((flip(s)-size(logo(:,:,1)))/2-[0.33*s(2),0]);
            alpha(1+corner(1):m(1)+corner(1),1+corner(2):m(2)+corner(2))=logo(:,:,4);
            cdata(1+corner(1):m(1)+corner(1),1+corner(2):m(2)+corner(2),:)=...
                logo(:,:,1:3);
            for i=1:3
                temp=cdata(:,:,i);
                temp(alpha==0 | temp==1)=color(i)*255;
                cdata(:,:,i)=temp;
            end
            
            alpha(alpha==0)=a;
            logo=h.Parent.Parent.UserData.Quail_Big;
            logo=imresize(logo,1/ceil(max(size(logo))/min(s)),'bilinear');
            logo=flipud(logo);
            m=size(logo(:,:,1));
            corner=round((flip(s)-size(logo(:,:,1)))/2+[s(2),0]);
            alpha(1+corner(1):m(1)+corner(1),1+corner(2):m(2)+corner(2))=...
                b*double(logo(:,:,4));
            alpha(alpha==0)=1;
            cdata(1+corner(1):m(1)+corner(1),1+corner(2):m(2)+corner(2),:)=...
                logo(:,:,1:3);
            for i=1:3
                temp=cdata(:,:,i);
                temp(alpha==b | temp==1)=color(i)*255;
                cdata(:,:,i)=temp;
            end
            alpha=im2double(alpha);
            cdata=im2double(cdata);
            if initialize
                delete(h.Children);
                p=surf(h,...
                    'XData',1:s(1),...
                    'YData',-s(2):s(2),...
                    'ZData',ones(2*s(2)+1,s(1)),...
                    'CData',cdata,...
                    'AlphaData',alpha,...
                    'FaceAlpha','flat',...
                    'FaceColor','flat',...
                    'EdgeAlpha',0,...
                    'AlphaDataMapping','none','Visible','on');
                text(h,1,1,sprintf('%s\n',...
                    'Quail Kit',' ',' ','Algorithms',' ',' ',' ',...
                    'Supervisor'),'FontUnits','normalized',...
                    'FontWeight','normal','Color',[1,1,1],...
                    'HorizontalAlignment','center');
                text(h,1,1,sprintf('%s\n',...
                    ' ',' ','Hanif Tiznobake',' ',' ',...
                    'Stephen Huang','Hanif Tiznobake',' ',' ',...
                    'Hamed Sari-Sarraf'),'FontUnits','normalized',...
                    'FontWeight','bold','Color',[1,1,1],...
                    'HorizontalAlignment','center');
            else
                p=h.Children(end);
                set(p,...
                    'XData',1:s(1),...
                    'YData',-s(2):s(2),...
                    'ZData',ones(2*s(2)+1,s(1)),...
                    'CData',cdata,...
                    'AlphaData',alpha,...
                    'FaceAlpha','flat',...
                    'FaceColor','flat',...
                    'EdgeAlpha',0,...
                    'AlphaDataMapping','none');
            end
            h.Children(1).Position(1:2)=[mean(p.XData),0.5*p.YData(1)];
            h.Children(2).Position(1:2)=[mean(p.XData),0.5*p.YData(1)];
        end
        
        function Object = Feedback(Object)
            if isnumeric(Object)
                temp=Object;
            else
                temp=Object.CData;
            end
            c=temp(1,1,:);
            temp2=temp;
            temp2(~isnan(temp))=NaN;
            for i=1:3
                temp3=temp2(:,:,i);
                temp3(isnan(temp(:,:,i)))=c(i);
                temp3(1,:)=c(i);
                temp3(:,1)=c(i);
                temp3(end,:)=c(i);
                temp3(:,end)=c(i);
                temp2(:,:,i)=temp3;
            end
            if isnumeric(Object)
                Object=temp2;
            else
                Object.CData=temp2;
            end
        end
        
        function app = Lock(app, state)
            switch state
                case 'on'
                    h=findobj(app.Fig,...
                        {'Enable','on','-and','-not','Parent',app.Toolbar});
                    set(h,'Enable','inactive');
                    h=findobj(app.Fig,...
                        {'Enable','on','-and','Parent',app.Toolbar});
                    set(h,'Enable','off');
                case 'off'
                    h=findobj(app.Fig,{'Enable','inactive'});
                    set(h,'Enable','on');
                    h=findobj(app.Fig,...
                        {'Visible','on','-and','Parent',app.Toolbar});
                    set(h,'Enable','on');
            end
        end
        
        function handles = Set12(app, handles, initialize)
            if initialize
                app.Two_Pop.String=app.Two_Pop.UserData(:,1);
            end
            switch app.One_Pop.Value
                case 1
                    set(app.Two.Children,'Enable','off');
                    app.One_List.String='';
                case 2
                    set(app.Two.Children,'Enable','on');
                    app.One_List.String=HT_DataAccess(handles,'query',...
                        ['SELECT DISTINCT [Started On] ',...
                        'FROM Recordings'],'cellarray');
            end
            switch app.Two_Pop.Value
                case 1
                    set(app.Two.Children(2:end),'Enable','off');
                    app.Two_List.String='';
                case 2
                    set(app.Two.Children,'Enable','on');
                    app.Two_List.String=cell(size(app.Two_Pop.UserData...
                        {app.Two_Pop.Value,2},1),1);
                    for i=1:size(app.Two_Pop.UserData{app.Two_Pop.Value,2},1)
                        switch class(app.Two_Pop.UserData{...
                                app.Two_Pop.Value,2}{i,1})
                            case {'char','string'}
                                format1='%s';
                            case {'uint64','logical'}
                                format1='%d';
                            case 'double'
                                format1='%0.3f';
                        end
                        switch class(app.Two_Pop.UserData{...
                                app.Two_Pop.Value,2}{i,2})
                            case {'char','string'}
                                format2='%s';
                            case {'uint64','logical'}
                                format2='%d';
                            case 'double'
                                format2='%0.3f';
                        end
                        app.Two_List.String{i}=...
                            sprintf([format1,': ',format2],...
                            app.Two_Pop.UserData{app.Two_Pop.Value,2}{i,1},...
                            app.Two_Pop.UserData{app.Two_Pop.Value,2}{i,2});
                    end
                case 3
                    set(app.Two.Children(2:end),'Enable','on');
                    Mics=app.Two_Pop.UserData{2,2};
                    app.Two_Pop.UserData{3,2} = HT_DataAccess(handles,'query',...
                        ['SELECT [ID], [ID] & ''. '' & ROUND([Start]) & '' by '' & [Created By]',...
                        'FROM Annotations ',...
                        'WHERE [Started On] = #', app.Data.Date,'# ',...
                        'AND [Recorder ID] IN (',sprintf('%d, ',Mics{[Mics{:,2}],1}),')'],'cellarray');
                    temp=string(app.Two_Pop.UserData{app.Two_Pop.Value,2});
                    app.Two_List.String = temp(:,2);
            end
            if app.Two_Pop.Value==2
                switch class(app.Two_Pop.UserData{app.Two_Pop.Value,2}...
                        {app.Two_List.Value,2})
                    case 'double'
                        app.Two_Slide.Visible='on';
                        app.Two_Slide.Style='slider';
                    case 'logical'
                        app.Two_Slide.Visible='on';
                        app.Two_Slide.Style='checkbox';
                    otherwise
                        app.Two_Slide.Visible='off';
                end
            end
            app.Data.Date=char(app.One_List.String(app.One_List.Value));
        end
        
        function handles = Set34(app, handles)
            temp=SH_FindCalls();
            app.Five_List.UserData=temp(2:end,:);
            app.Five_Pop.String{2}=temp{1,2};
            Parameters=app.Four_List.UserData{app.Four_Pop.Value};
            switch app.Four_Pop.Value
                case 1
                    set(app.Four.Children(2:end),'Enable','off');
                    app.Four_List.String='';
                case 2
                    set(app.Four.Children,'Enable','on');
                    app.Four_List.String=cell(size(Parameters,2),1);
                    for i=1:size(Parameters,2)
                        switch class(Parameters{2,i})
                            case {'char','string'}
                                format='%s';
                            case 'logical'
                                format='%d';
                            case 'double'
                                format='%0.3f';
                        end
                        app.Four_List.String{i}=...
                            sprintf(['%s: ',format],Parameters{1,i},Parameters{2,i});
                    end
            end
            switch class(Parameters{2,app.Four_List.Value})
                case 'double'
                    app.Four_Slide.Visible='on';
                    app.Four_Slide.Style='slider';
                case 'logical'
                    app.Four_Slide.Visible='on';
                    app.Four_Slide.Style='checkbox';
                otherwise
                    app.Four_Slide.Visible='off';
            end
            
            switch app.Five_Pop.Value
                case 1
                    set(app.Five.Children(2:end),'Enable','off');
                    app.Five_List.UserData={};
                    app.Five_List.String='';
                case 2
                    set(app.Five.Children,'Enable','on');
                    app.Five_List.String=cell(size(app.Five_List.UserData,1),1);
                    for i=1:size(app.Five_List.UserData,1)
                        switch class(app.Five_List.UserData{i,2})
                            case {'char','string'}
                                format='%s';
                            case 'logical'
                                format='%d';
                            case 'double'
                                format='%0.3f';
                        end
                        app.Five_List.String{i}=...
                            sprintf(['%s: ',format],app.Five_List.UserData{i,1},...
                            app.Five_List.UserData{i,2});
                    end
            end
            if app.Five_Pop.Value>1
                switch class(app.Five_List.UserData{app.Five_List.Value,2})
                    case 'double'
                        app.Five_Slide.Visible='on';
                        app.Five_Slide.Style='slider';
                    case 'logical'
                        app.Five_Slide.Visible='on';
                        app.Five_Slide.Style='checkbox';
                    otherwise
                        app.Five_Slide.Visible='off';
                end
            end
        end
        
        function handles = SetAxis(app, handles, initialize)
            if initialize
                delete(app.Panel.Children);
                for k=1:size(app.Two_Pop.UserData{2,2},1)
                    app.Graphics.Axis(1,k)=axes(app.Panel,'Box','on');
                    app.Graphics.Axis(2,k)=axes(app.Panel,'Box','on');
                    app.Graphics.Axis(3,k)=axes(app.Panel,'Box','on');
                    app.Graphics.Watermark=axes(app.Panel,'Color','none','XColor',...
                        'none','YColor','none',...
                        'XTick',[],'YTick',[],'Units','pixels','NextPlot','add');
                    set(app.Graphics.Axis(:),'NextPlot','add');
                    for i=1:size(app.Graphics.Axis,1)
                        app.Graphics.Plot(i,k,1)=...
                            plot(app.Graphics.Axis(i,k),NaN(1,2),NaN(1,2));
                        app.Graphics.Plot(i,k,2)=...
                            plot(app.Graphics.Axis(i,k),NaN(1,2),NaN(1,2),...
                            'Color','r','LineWidth',1);
                        app.Graphics.Scatter(i,k,1)=...
                            scatter(app.Graphics.Axis(i,k),NaN(1,2),NaN(1,2),'s');
                        app.Graphics.Scatter(i,k,2)=...
                            scatter(app.Graphics.Axis(i,k),NaN(1,2),NaN(1,2),...
                            'MarkerFaceColor','r');
                        app.Graphics.Surf(i,k,1)=...
                            surf(app.Graphics.Axis(i,k),NaN(1,2),NaN(1,2),NaN(2,2),...
                            'EdgeColor','none');
                        app.Graphics.Surf(i,k,2)=...
                            surf(app.Graphics.Axis(i,k),NaN(1,2),NaN(1,2),NaN(2,2),...
                            'EdgeColor','none','FaceColor','interp','FaceAlpha','interp');
                        app.Graphics.Surf(i,k,3)=...
                            surf(app.Graphics.Axis(i,k),NaN(1,2),NaN(1,2),NaN(2,2),...
                            'EdgeColor','none','FaceColor','interp','FaceAlpha','interp');
                        app.Graphics.Patch(i,k,1)=...
                            fill(app.Graphics.Axis(i,k),NaN,NaN,'k',...
                            'FaceAlpha',0.1,'EdgeAlpha',1);
                        app.Graphics.Line(i,k,1)=...
                            line(app.Graphics.Axis(i,k),NaN(1,2),NaN(1,2),NaN(1,2),...
                            'LineWidth',1,'Visible','off');
                    end
                    set(app.Graphics.Axis(:),'Visible','off','XMinorTick','on',...
                        'YMinorTick','on','YTickLabel',[],...
                        'NextPlot','replacechildren',...
                        'GridLineStyle','none','View',[0,90],...
                        'Color',1*[1,1,1],'FontSize',8);
                    axis(app.Graphics.Axis(3,k),'tight')
                    set(app.Graphics.Axis(3,k),'XTick',[],'YTick',-1:0.5:1,...
                        'YLim',[-1,1],'YTickLabelMode','auto');
                    set(app.Graphics.Plot(1,k,2),'Color',[1,1,1]);
                end
            else
                set(app.Graphics.Plot(:),'XData',NaN,'YData',NaN);
                set(app.Graphics.Scatter(:),'XData',NaN,'YData',NaN);
                set(app.Graphics.Surf(:),...
                    'XData',NaN(1,2),'YData',NaN(1,2),'ZData',NaN(2,2));
                set(app.Graphics.Patch(:),'XData',NaN,'YData',NaN);
                set(app.Graphics.Line(:),...
                    'XData',NaN,'YData',NaN,'ZData',NaN);
            end
        end
        
        function handles = SetGraphics_All(app, handles, varargin)
            handles=SetTheme(handles);
            handles=SetGraphics_Toolbar(handles);
            handles=SetGraphics_GroupPlay(handles);
            for i=1:length(app.Group45.Children)
                set(app.Group45.Children(i).Children,...
                    'ForegroundColor',app.Graphics.Color);
            end
            for i=1:length(app.Group123.Children)
                set(app.Group123.Children(i).Children,...
                    'ForegroundColor',app.Graphics.Color);
            end
            set(app.Graphics.Plot(:,:,1),'Color',app.Graphics.Color);
            set(app.Graphics.Plot(1,:,2),...
                'Color',app.Graphics.Colormap(32,:));
            set(app.Graphics.Scatter(3,:,1),...
                'MarkerEdgeColor',app.Graphics.Color);
            set(app.Graphics.Line(:),'Color',app.Graphics.Color);
            set(app.Graphics.Line(3,:,:),'Color','r');
            set(app.Graphics.Patch(:),'FaceColor',app.Graphics.Color,...
                'EdgeColor',app.Graphics.Color);
            colormap(app.Fig,app.Graphics.Colormap);
        end
        
        function handles = SetGraphics_GroupPlay(app, handles, varargin)
            handles=SetTheme(handles);
            app.Play.CData=app.icons.Play;
            app.Back.CData=app.icons.Left;
            app.Skip.CData=app.icons.Right;
            app.Next.CData=app.icons.Next;
            app.Previous.CData=app.icons.Previous;
            app.QueueMode.CData=app.icons.QueueMode;
            app.Reset.CData=app.icons.Reset;
            app.Clear.CData=app.icons.Clear;
            app.PlayMode.CData=...
                app.icons.PlayMode(:,:,3*app.PlayMode.UserData+(1:3));
            app.Mode.CData=...
                app.icons.Mode(:,:,3*app.Mode.UserData+(1:3));
            app.Debug.CData=...
                app.icons.Debug(:,:,3*app.Debug.UserData+(1:3));
        end
        
        function handles = SetGraphics_Toolbar(app, handles, varargin)
            handles=SetTheme(handles);
            Buttons={'Animation','InvertColors','Sound','Capture'};
            for j=1:length(Buttons)
                if strcmp(app.(Buttons{j}).State,'off')
                    app.(Buttons{j}).CData=app.icons.(Buttons{j});
                else
                    app.(Buttons{j}).CData=Feedback(app.icons.(Buttons{j}));
                end
            end
            app.HT.CData=app.icons.HT;
            app.Save.CData=app.icons.Save;
            app.Display.CData=app.icons.Display;
            app.Zoom.CData=app.icons.Zoom;
            app.Theme.CData=app.icons.Theme;
        end
        
        function handles = SetLayout(app, handles, frames)
            activeMics=find([app.Two_Pop.UserData{2,2}{:,2}]);
            activeAxes=find(...
                [app.Four_List.UserData{app.Four_Pop.Value}{2,:}]==true);
            inactiveMics=find(~[app.Two_Pop.UserData{2,2}{:,2}]);
            inactiveAxes=find(...
                [app.Four_List.UserData{app.Four_Pop.Value}{2,:}]==false);
            set([findobj(app.Fig,'-depth',1,'-and','-not','Tag','Toolbar');...
                app.Group123.Children;...
                app.Group45.Children;...
                app.Panel.Children],'Units','pixels');
            W=app.Fig.Position(3);
            H=app.Fig.Position(4);
            M=app.UserData.Margin;
            s=app.UserData.Spacing;
            t=5*M;
            nd=length(app.Group123.Children);
            na=length(app.Group45.Children);
            if W>H
                h=round(H/4);
                w=round(h*200/140);
                app.Panel.Position=[M,h+2*M,W-2*M+1,H-h-3*M+1];
                app.Group123.Position=[w+2*M,M,(W-w-4*M+1)*nd/(na+nd),h];
                app.Group45.Position=[sum(app.Group123.Position([1,3]))+M,...
                    M,(W-w-4*M+1)*na/(na+nd),h];
            else
                w=round(W/5);
                h=round(w*140/200);
                app.Panel.Position=[M,2*h+3*M,W-2*M+1,H-2*h-4*M+1];
                app.Group123.Position=[w+2*M,M,W-w-3*M+1,h];
                app.Group45.Position=[M,h+2*M,W-2*M+1,h];
            end
            app.GroupPlay.Position=[M,M,w,h];
            app.One.Position=[s,s,(app.Group123.Position(3)-(nd+1)*s)/nd,...
                app.Group123.Position(4)-2*s];
            app.Two.Position=[sum(app.One.Position([1,3]))+s,s,...
                (app.Group123.Position(3)-(nd+1)*s)/nd,...
                app.Group123.Position(4)-2*s];
            app.Four.Position=[s,s,(app.Group45.Position(3)-(na+1)*s)/na,...
                app.Group45.Position(4)-2*s];
            app.Five.Position=[sum(app.Four.Position([1,3]))+s,s,...
                (app.Group45.Position(3)-(na+1)*s)/na,...
                app.Group45.Position(4)-2*s];
            set(app.Group45,'Visible','on');
            if app.UserData.LayoutMode==2
                set(app.Group45,'Visible','off');
                if W>H
                    app.Panel.Position=[w+2*M,M,W-w-3*M+1,H-2*M+1];
                    app.Group123.Position=[M,h+2*M,w,H-h-3*M+1];
                    app.Two.Position=[s,s,app.Group123.Position(3)-2*s,...
                        (app.Group123.Position(4)-(nd+1)*s)/nd];
                    app.One.Position=[s,sum(app.Two.Position([2,4]))+s,...
                        app.Group123.Position(3)-2*s,...
                        (app.Group123.Position(4)-(nd+1)*s)/nd];
                else
                    app.Panel.Position=[M,h+2*M,W-2*M+1,H-h-3*M+1];
                end
            end
            n=frames-1;
            if n>1
                a=[1,1,1;n^2,n,1;n*(n+1)*(2*n+1)/6,n*(n+1)/2,n]\[0.1;0.1;n];
                HT_Smooth=@(x,a) cumsum(a(1)*x.^2+a(2)*x+a(3));
            else
                a=[1,1,1];
                HT_Smooth=@(x,a) x;
            end
            m=length(activeAxes);
            l=length(activeMics);
            temp=app.Graphics.Axis(activeAxes,activeMics)';
            Start=cat(1,temp.Position);
            End=repmat([t,t,app.Panel.Position(3:4)-2*t],l*m,1);
            End(:,4)=(app.Panel.Position(4)-(m+1)*t)/(l*m);
            m=1;
            l=1;
            for i=activeAxes
                for k=activeMics
                    End(l,2)=app.Panel.Position(4)-l*End(l,4)-m*t;
                    l=l+1;
                end
                m=m+1;
            end
            Step=(End-Start)/n;
            if max(Step(:))<1
                n=1;
                Step=(End-Start)/n;
            end
            Path=(repmat(permute(Start,[3,2,1]),n,1,1)+...
                permute(Step,[3,2,1]).*HT_Smooth((1:n)',a));
            for i=inactiveAxes
                set([app.Graphics.Axis(i,:);...
                    app.Graphics.Axis(i,:).Children],'Visible','off');
            end
            set([reshape(app.Graphics.Axis(:,inactiveMics),[],1);...
                cat(1,app.Graphics.Axis(:,inactiveMics).Children)],...
                'Visible','off');
            set(app.Graphics.Watermark,'Position',app.Panel.Position);
            app.Graphics.Credits=...
                Credits(app.Graphics.Watermark,app.Graphics.Color,false);
            for l=1:size(Path,1)
                j=1;
                for i=activeAxes
                    for k=activeMics
                        set(app.Graphics.Axis(i,k),'Position',Path(l,:,j));
                        j=j+1;
                    end
                end
                drawnow;
            end
            for i=activeAxes
                set([app.Graphics.Axis(i,activeMics);...
                    app.Graphics.Axis(i,activeMics).Children],'Visible','on');
            end
        end
        
        function handles = SetPlay(app, handles)
            switch app.Mode.UserData
                case 0
                    app.UserData.LayoutMode=1;
                case 1
                    app.UserData.LayoutMode=2;
                case 2
                    app.UserData.LayoutMode=2;
            end
        end
        
        function handles = SetTheme(app, handles)
            colormaps={'parula','jet','hsv','hot','cool','spring','summer','autumn',...
                'winter','gray','bone','copper','pink'};
            if strcmp(app.InvertColors.State,'on')
                temp1='app.Graphics.Colormap=flipud(';
                temp2=');';
            else
                temp1='app.Graphics.Colormap=';
                temp2=';';
            end
            eval([temp1,char(colormaps{app.Theme.UserData}),temp2]);
            app.Theme.TooltipString=['Theme (',...
                char(colormaps(app.Theme.UserData)),')'];
            app.Graphics.Color=app.Graphics.Colormap(32,:)/...
                (3*max(app.Graphics.Colormap(32,:)));
            app.icons=app.Fig.UserData;
            temp=fieldnames(app.icons);
            for i=1:length(temp)
                app.icons.(char(temp(i)))(:,:,1:3:end)=...
                    app.icons.(char(temp(i)))(:,:,1:3:end)*...
                    app.Graphics.Color(1);
                app.icons.(char(temp(i)))(:,:,2:3:end)=...
                    app.icons.(char(temp(i)))(:,:,2:3:end)*...
                    app.Graphics.Color(2);
                app.icons.(char(temp(i)))(:,:,3:3:end)=...
                    app.icons.(char(temp(i)))(:,:,3:3:end)*...
                    app.Graphics.Color(3);
            end
        end
        
        function handles = SetToolbar(app, handles)
            if strcmp(app.Animation.State,'on')
                app.UserData.Frames=20;
            else
                app.UserData.Frames=2;
            end
            handles=SetGraphics_Toolbar(handles);
        end
        
        function handles = SetValues(app, handles)
            app.UserData.AnnMode=0;
            app.UserData.Frames=20;
            app.UserData.ZoomMode=0;
            app.UserData.LayoutMode=0;
            app.UserData.Freq=[0,4000];
            app.UserData.Margin=5;
            app.UserData.Spacing=5;
        end
        
        function handles = SetView(app, handles, flag)
            set(app.Fig.Children([1,2,3,4]),'Units','pixels');
            set(app.Graphics.Axis(:),'Units','pixels');
            flag2=0;
            if app.Data.TS.Time(app.Data.Edges(app.Data.j+1))>...
                    app.Graphics.Patch(1,1,1).XData(1)
                flag2=1;
            end
            active=find([app.Two_Pop.UserData{2,2}{:,2}]);
            if ~flag
                set([app.Graphics.Axis(3,active).Children],...
                    'Visible','off');
            end
            switch app.UserData.ZoomMode
                case 0
                    Trans(1:2)=app.Data.TS.Time([1,end]);
                    Trans(3:4)=max(abs(app.Graphics.Plot(1,1,1).YData(2:end)))*[-1,1];%workaround
                    Trans(7)=0.1;
                case 1
                    Trans(1:2)=...
                        [app.Data.TS.Time(app.Data.Edges(app.Data.j))-295,...
                        app.Data.TS.Time(app.Data.Edges(app.Data.j+1))+295];
                    Trans(3:4)=max(abs(app.Graphics.Plot(1,1,1).YData(round(...
                        max(2,0.001*(app.Data.Edges(app.Data.j)-295*...
                        app.Data.fs)):...
                        min(length(app.Graphics.Plot(1,1,1).XData),...
                        0.001*(app.Data.Edges(app.Data.j+1)+295*...
                        app.Data.fs))))))*[-1,1];%workaround
                    Trans(7)=0.1;
                case 2
                    Trans(1:2)=[app.Data.TS.Time(app.Data.Edges(app.Data.j))-25,...
                        app.Data.TS.Time(app.Data.Edges(app.Data.j+1))+25];
                    Trans(3:4)=max(abs(app.Data.TS.Data(app.Data.Edges(app.Data.j):...
                        app.Data.Edges(app.Data.j+1))))*[-1,1];
                    Trans(7)=1;
                case 3
                    Trans(1:2)=[app.Data.TS.Time(app.Data.Edges(app.Data.j)),...
                        app.Data.TS.Time(app.Data.Edges(app.Data.j+1))];
                    Trans(3:4)=max(abs(...
                        app.Data.TS.Data(app.Data.Edges(app.Data.j):...
                        app.Data.Edges(app.Data.j+1))))*[-1,1];
                    Trans(7)=1;
            end
            if ismember(app.UserData.ZoomMode,[2,3])
                if ~flag2
                    set(app.Graphics.Patch(1,active,1),...
                        'XData',app.Data.TS.Time(...
                        [app.Data.Edges(app.Data.j-flag2),...
                        app.Data.Edges(app.Data.j+2-flag2),...
                        app.Data.Edges(app.Data.j+2-flag2),...
                        app.Data.Edges(app.Data.j-flag2)]));
                    for k=active
                        set(app.Graphics.Plot(1,k,2),...
                            'XData',app.Data.TS.Time(...
                            app.Data.Edges(app.Data.j-flag2):...
                            app.Data.Edges(app.Data.j+2-flag2)),...
                            'YData',app.Data.TS.Data(...
                            app.Data.Edges(app.Data.j-flag2):...
                            app.Data.Edges(app.Data.j+2-flag2),k));
                    end
                end
            else
                set(app.Graphics.Plot(1,active,2),...
                    'XData',NaN,...
                    'YData',NaN,...
                    'ZData',NaN);
            end
            Trans(5:6)=app.Data.TS.Time([app.Data.Edges(app.Data.j),...
                app.Data.Edges(app.Data.j+1)]);
            
            Object=[app.Graphics.Axis(1,active)',app.Graphics.Axis(1,active)',...
                app.Graphics.Axis(2,active)',app.Graphics.Patch(1,active,1)'];
            Prop={'XLim','YLim','XLim','FaceAlpha'};
            
            HT_Transition(Object,Prop,Trans,app.UserData.Frames,handles);
            for k=active
                if ~flag && ~ismember(app.UserData.ZoomMode,[2,3])
                    set(app.Graphics.Patch(1,k,1),...
                        'XData',app.Data.TS.Time(...
                        [app.Data.Edges(app.Data.j),...
                        app.Data.Edges(app.Data.j+1),...
                        app.Data.Edges(app.Data.j+1),...
                        app.Data.Edges(app.Data.j)]));
                    set(app.Graphics.Line(1,k,1),...
                        'XData',app.Data.TS.Time(...
                        app.Data.Edges(app.Data.j))*[1,1],...
                        'YData',2*app.Data.Max*[-1,1],...
                        'ZData',[0,0]);
                end
                if ismember(app.UserData.ZoomMode,[2,3])
                    set(app.Graphics.Plot(1,k,2),...
                        'XData',app.Data.TS.Time(...
                        app.Data.Edges(app.Data.j):...
                        app.Data.Edges(app.Data.j+1)),...
                        'YData',app.Data.TS.Data(...
                        app.Data.Edges(app.Data.j):...
                        app.Data.Edges(app.Data.j+1),k),...
                        'ZData',ones(app.Data.Edges(app.Data.j+1)-...
                        app.Data.Edges(app.Data.j)+1,1));
                end
                if ~flag && ismember(app.UserData.ZoomMode,[2,3])
                    set(app.Graphics.Patch(1,k,1),...
                        'XData',app.Data.TS.Time(...
                        [app.Data.Edges(app.Data.j),...
                        app.Data.Edges(app.Data.j+1),...
                        app.Data.Edges(app.Data.j+1),...
                        app.Data.Edges(app.Data.j)]));
                    set(app.Graphics.Line(1,k,1),...
                        'XData',app.Data.TS.Time(...
                        app.Data.Edges(app.Data.j))*[1,1],...
                        'YData',2*app.Data.Max*[-1,1],...
                        'ZData',[0,0]);
                end
                if app.Four_Pop.UserData
                    set(app.Graphics.Axis(2,k),...
                        'XLim',app.Data.TS.Time(...
                        [app.Data.Edges(app.Data.j),...
                        app.Data.Edges(app.Data.j+1)]),...
                        'YLim',app.UserData.Freq);
                    set(app.Graphics.Line(2,k,1),...
                        'YData',app.Graphics.Axis(2,k).YLim,...
                        'ZData',1+max(max(app.Graphics.Surf(2,k,1).ZData))*[1,1]);
                end
                temp=app.UserData.Freq(1):1000:app.UserData.Freq(end);
                set(app.Graphics.Axis(2,k),...
                    'YTick',temp,...
                    'YTickLabel',string([char(string(temp/1000)'),...
                    repmat('k',length(temp),1)]));
                axis(app.Graphics.Axis(3,k),'tight');
                for i=find([app.Four_List.UserData{app.Four_Pop.Value}{2,1:3}]==true)
                    set([app.Graphics.Axis(i,k).Children],'Visible','on');
                end
                guidata(app.Fig,handles);
            end
        end
        
        function handles = Wait(app, handles, state)
            h=repmat(app.Graphics.Watermark,1,2);
            switch state
                case 'on'
                    handles=Lock(handles,'on');
                    app.Graphics.Credits.Visible='on';
                    h=HT_Transition(h,{'XLim','YLim'},...
                        [round([1,h(1).Position(3),1,...
                        h(1).Position(4)])],app.UserData.Frames,handles);
                    app.Graphics.Watermark=h(1);
                case 'off'
                    h=HT_Transition(h,{'XLim','YLim'},...
                        round([h(1).Position(3)/2+[-20,-18],...
                        h(1).Position(4)/2+[18,20]]),app.UserData.Frames,handles);
                    app.Graphics.Watermark=h(1);
                    app.Graphics.Credits.Visible='off';
                    handles=Lock(handles,'off');
            end
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function QuailKit_OpeningFcn(app)
            addpath(genpath(pwd));
            app.Path.Recordings='../../Quail Call - Recordings/';
            app.Path.Spectrograms='../../Quail Call - Shared/data/spectrograms/';
            app.Path.Results='../../Quail Call - Shared/results/';
            handles=SetValues(handles);
            handles=SetAxis(handles,true);
            handles=SetGraphics_All(handles);
            handles=SetToolbar(handles);
            handles=SetPlay(handles);
            handles=Set12(handles,true);
            handles=Set34(handles);
            app.Graphics.Credits=Credits(...
                app.Graphics.Watermark,...
                app.Graphics.Color,true);
            app.Graphics.Watermark=HT_Transition(...
                app.Graphics.Watermark,{'YLim'},...
                [1-app.Graphics.Watermark.Position(4),0],...
                app.UserData.Frames,handles);
            handles=Wait(handles,'off');
            app.Data.j=1;
            handles=SetLayout(handles,app.UserData.Frames);
            app.output = hObject;
            guidata(hObject,handles);
        end

        % Button pushed function: Back
        function Back_Callback(app, event)
            hObject.UserData=1;
            if app.Data.j>1
                app.Data.j=app.Data.j-1;
            else
                app.Data.j=length(app.Data.Bins);
            end
            handles=HT_Compute(handles);
            handles=SetView(handles,false);
            guidata(hObject,handles);
        end

        % Button pushed function: Clear
        function Clear_Callback(app, event)
            app.Data.j=1;
            handles=HT_Compute(handles);
            handles=SetView(handles,false);
            guidata(hObject,handles);
        end

        % Button pushed function: Debug
        function Debug_Callback(app, event)
            app.Debug.UserData=rem(app.Debug.UserData+1,2);
            handles=SetGraphics_All(handles);
            handles=SetToolbar(handles);
            handles=SetPlay(handles);
            handles=Set12(handles,false);
            handles=Set34(handles);
            handles=Wait(handles,'off');
            handles=SetLayout(handles);
            guidata(hObject,handles);
        end

        % Key press function: Fig
        function Fig_KeyPressFcn(app, event)
            switch eventdata.Key
                case 'rightarrow'
                    app.Data.j=min(app.Data.j+1,length(app.Data.Bins)-1);
                case 'leftarrow'
                    app.Data.j=max(1,app.Data.j-1);
                case 'downarrow'
                    app.Data.j=min(app.Data.j+10,length(app.Data.Bins)-1);
                case 'uparrow'
                    app.Data.j=max(1,app.Data.j-10);
            end
            if ismember(eventdata.Key,{'rightarrow','leftarrow','downarrow','uparrow'})
                set(app.Graphics.Axis(3).Children,...
                    'Visible','off');
                set(app.Graphics.Axis(2).Children,...
                    'Visible','off');
                app.Graphics.Patch(1).XData=app.Data.TS.Time(...
                    [app.Data.Edges(app.Data.j),...
                    app.Data.Edges(app.Data.j+1),...
                    app.Data.Edges(app.Data.j+1),...
                    app.Data.Edges(app.Data.j)]);
                set(app.Graphics.Axis(2),...
                    'XLim',app.Graphics.Patch(1).XData(1:2));
                guidata(hObject,handles);
            end
        end

        % Key release function: Fig
        function Fig_KeyReleaseFcn(app, event)
            handles = HT_Compute(handles);
            handles=SetView(handles,false);
            guidata(app.Fig,handles);
        end

        % Size changed function: Fig
        function Fig_SizeChangedFcn(app, event)
            handles=SetLayout(handles,2);
            guidata(app.Fig,handles);
        end

        % Value changed function: Five_List
        function Five_List_Callback(app, event)
            handles = Set34(handles);
            handles=SetLayout(handles,app.UserData.Frames);
            value=hObject.UserData{hObject.Value,2};
            switch class(value)
                case 'logical'
                    app.Five_Slide.Value=double(value);
                case 'double'
                    app.Five_Slide.Value=value;
            end
            guidata(hObject,handles);
        end

        % Value changed function: Five_Pop
        function Five_Pop_Callback(app, event)
            handles = Set34(handles);
            handles=SetLayout(handles,app.UserData.Frames);
            handles=HT_Compute(handles);
            handles=SetView(handles,false);
            guidata(hObject,handles);
        end

        % Value changed function: Five_Slide
        function Five_Slide_Callback(app, event)
            switch hObject.Style
                case 'slider'
                    temp=hObject.Value;
                case 'checkbox'
                    temp=logical(hObject.Value);
            end
            app.Five_List.UserData{app.Five_List.Value,2}=temp;
            handles = Set34(handles);
            handles=SetLayout(handles);
            handles=HT_Compute(handles);
            handles=SetView(handles,false);
            guidata(hObject,handles);
        end

        % Value changed function: Four_List
        function Four_List_Callback(app, event)
            handles = Set34(handles);
            value=hObject.UserData{app.Four_Pop.Value}{2,hObject.Value};
            switch class(value)
                case 'logical'
                    app.Four_Slide.Value=double(value);
                case 'double'
                    app.Four_Slide.Value=value;
            end
            guidata(hObject,handles);
        end

        % Value changed function: Four_Pop
        function Four_Pop_Callback(app, event)
            handles = Set34(handles);
            handles=HT_Compute(handles);
            handles=SetView(handles,false);
            handles=SetLayout(handles);
            guidata(hObject,handles);
        end

        % Value changed function: Four_Slide
        function Four_Slide_Callback(app, event)
            switch hObject.Style
                case 'slider'
                    temp=hObject.Value;
                case 'checkbox'
                    temp=logical(hObject.Value);
            end
            app.Four_List.UserData{app.Four_Pop.Value}...
                {2,app.Four_List.Value}=temp;
            handles = Set34(handles);
            handles=SetLayout(handles,app.UserData.Frames);
            handles=HT_Compute(handles);
            handles=SetView(handles,false);
            guidata(hObject,handles);
        end

        % Button pushed function: Mode
        function Mode_Callback(app, event)
            app.Mode.UserData=rem(app.Mode.UserData+1,3);
            handles=SetGraphics_All(handles);
            handles=SetToolbar(handles);
            handles=SetPlay(handles);
            handles=Set12(handles,false);
            handles=Set34(handles);
            handles=Wait(handles,'off');
            handles=SetLayout(handles);
            guidata(hObject,handles);
        end

        % Button pushed function: Next
        function Next_Callback(app, event)
            app.Data.j=1;
            app.One_Pop.Value=app.One_Pop.Value+1;
            handles=SetGraphics_All(handles);
            handles=SetToolbar(handles);
            handles=SetPlay(handles);
            handles=Set12(handles,false);
            handles=Set34(handles);
            handles=Wait(handles,'off');
            handles=SetLayout(handles);
            handles=HT_DataAccess(handles,'read');
            guidata(hObject,handles);
        end

        % Value changed function: One_List
        function One_List_Callback(app, event)
            handles=Wait(handles,'on');
            handles=Set12(handles,false);
            handles=HT_DataAccess(handles,'prepare');
            handles=SetGraphics_All(handles);
            handles=SetToolbar(handles);
            handles=SetPlay(handles);
            handles=Set12(handles,false);
            handles=Set34(handles);
            handles=Wait(handles,'off');
            guidata(hObject,handles);
        end

        % Value changed function: One_Pop
        function One_Pop_Callback(app, event)
            handles=Wait(handles,'on');
            handles=Set12(handles,false);
            app.One_List.Value=1;
            handles=HT_DataAccess(handles,'read');
            handles=HT_Compute(handles);
            handles=SetView(handles,false);
            handles=SetGraphics_All(handles);
            handles=SetToolbar(handles);
            handles=SetPlay(handles);
            handles=Set12(handles,false);
            handles=Set34(handles);
            handles=Wait(handles,'off');
            guidata(hObject,handles);
        end

        % Button pushed function: PlayMode
        function PlayMode_Callback(app, event)
            app.PlayMode.UserData=rem(app.PlayMode.UserData+1,4);
            handles=SetGraphics_All(handles);
            handles=SetToolbar(handles);
            handles=SetPlay(handles);
            handles=Set12(handles,false);
            handles=Set34(handles);
            handles=Wait(handles,'off');
            handles=SetLayout(handles);
            guidata(hObject,handles);
        end

        % Button pushed function: Play
        function Play_Callback(app, event)
            if app.Data.Audio.isplaying
                app.Data.Audio.pause;
            elseif hObject.UserData==1
                hObject.UserData=0;
            else
                hObject.UserData=1;
                hObject.CData=app.icons.Pause;
                flag=false;
                while hObject.UserData==1
                    if app.PlayMode.UserData==0
                        app.Skip.UserData=0;
                        app.Back.UserData=0;
                        hObject.UserData=0;
                    else
                        if flag
                            if app.Data.j<length(app.Data.Edges)-2
                                app.Data.j=app.Data.j+1;
                                if app.PlayMode.UserData==1
                                    hObject.UserData=0;
                                end
                            elseif app.PlayMode.UserData==3
                                app.Data.j=1;
                            elseif app.QueueMode.Value==1
                                Next_Callback(app.Next, eventdata, handles);
                                app.Skip.UserData=0;
                            end
                            handles=HT_Compute(handles);
                            handles=SetView(handles,false);
                        end
                    end
                    if strcmp(app.Sound.State,'on')
                        app.Data.Audio.resume;
                        set(app.Graphics.Line(1:2),'Visible','on');
                        waitfor(app.Data.Audio,'Running','off');
                        set(app.Graphics.Line(1:2),'Visible','off');
                    end
                    flag=true;
                end
            end
            hObject.UserData=0;
            hObject.CData=app.icons.Play;
            guidata(hObject,handles);
        end

        % Button pushed function: Previous
        function Previous_Callback(app, event)
            if app.One_Pop.Value>2
                app.One_Pop.Value=app.One_Pop.Value-1;
                handles=SetGraphics_All(handles);
                handles=SetToolbar(handles);
                handles=SetPlay(handles);
                handles=Set12(handles,false);
                handles=Set34(handles);
                handles=Wait(handles,'off');
                handles=SetLayout(handles);
                app.Data.j=1;
                handles=HT_DataAccess(handles,'read');
                guidata(hObject,handles);
            end
        end

        % Value changed function: QueueMode
        function QueueMode_Callback(app, event)
            app.QueueMode.UserData=rem(app.QueueMode.UserData+1,2);
            handles=SetGraphics_All(handles);
            handles=SetToolbar(handles);
            handles=SetPlay(handles);
            handles=Set12(handles,false);
            handles=Set34(handles);
            handles=Wait(handles,'off');
            handles=SetLayout(handles);
            guidata(hObject,handles);
        end

        % Button pushed function: Reset
        function Reset_Callback(app, event)
            handles=SetAxis(handles,initialize);
            handles=Wait(handles,'off');
            app.Data.j=1;
            handles=SetLayout(handles,app.UserData.Frames);
            handles=HT_DataAccess(handles,'read');
            handles=HT_Compute(handles);
            handles=SetView(handles,false);
            guidata(hObject,handles);
        end

        % Button pushed function: Skip
        function Skip_Callback(app, event)
            hObject.UserData=1;
            if app.Data.j<length(app.Data.Bins)-1
                app.Data.j=app.Data.j+1;
            else
                app.Data.j=1;
            end
            handles=HT_Compute(handles);
            handles=SetView(handles,false);
            guidata(hObject,handles);
        end

        % Value changed function: Two_List
        function Two_List_Callback(app, event)
            value=app.Two_Pop.UserData{app.Two_Pop.Value,2}{hObject.Value,2};
            switch class(value)
                case 'logical'
                    app.Two_Slide.Value=double(value);
                case 'double'
                    app.Two_Slide.Value=value;
            end
            handles=Set12(handles,false);
            guidata(hObject,handles);
        end

        % Value changed function: Two_Pop
        function Two_Pop_Callback(app, event)
            app.Two_List.Value = 1;
            handles=Set12(handles,false);
            guidata(hObject,handles);
        end

        % Value changed function: Two_Slide
        function Two_Slide_Callback(app, event)
            handles=Wait(handles,'on');
            switch hObject.Style
                case 'slider'
                    temp=hObject.Value;
                case 'checkbox'
                    temp=logical(hObject.Value);
            end
            app.Two_Pop.UserData{app.Two_Pop.Value,2}...
                {app.Two_List.Value,2}=temp;
            handles=Set12(handles,false);
            handles=SetLayout(handles,app.UserData.Frames);
            handles=HT_DataAccess(handles,'read');
            handles=HT_Compute(handles);
            handles=SetView(handles,false);
            handles=SetGraphics_All(handles);
            handles=SetToolbar(handles);
            handles=SetPlay(handles);
            handles=Set12(handles,false);
            handles=Set34(handles);
            handles=Wait(handles,'off');
            guidata(hObject,handles);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Fig = uifigure;
            app.Fig.Name = 'Quail Kit (Beta)';
            app.Fig.SizeChangedFcn = createCallbackFcn(app, @Fig_SizeChangedFcn, true);
            app.Fig.KeyPressFcn = createCallbackFcn(app, @Fig_KeyPressFcn, true);
            app.Fig.KeyReleaseFcn = createCallbackFcn(app, @Fig_KeyReleaseFcn, true);
            app.Group45 = uipanel(app.Fig);
            app.Five = uibuttongroup(app.Group45);
            app.Five_Slide = uislider(app.Five);
            app.Five_Slide.Limits = [0 1];
            app.Five_Slide.MajorTicks = [];
            app.Five_Slide.ValueChangedFcn = createCallbackFcn(app, @Five_Slide_Callback, true);
            app.Five_Slide.MinorTicks = [];
            app.Five_List = uilistbox(app.Five);
            app.Five_List.Items = {};
            app.Five_List.ValueChangedFcn = createCallbackFcn(app, @Five_List_Callback, true);
            app.Five_List.Value = {};
            app.Five_Pop = uidropdown(app.Five);
            app.Five_Pop.Items = {'Detection Off', 'Template Matching'};
            app.Five_Pop.ValueChangedFcn = createCallbackFcn(app, @Five_Pop_Callback, true);
            app.Five_Pop.Value = 'Detection Off';
            app.Four = uibuttongroup(app.Group45);
            app.Four_List = uilistbox(app.Four);
            app.Four_List.Items = {};
            app.Four_List.ValueChangedFcn = createCallbackFcn(app, @Four_List_Callback, true);
            app.Four_List.Value = {};
            app.Four_Slide = uislider(app.Four);
            app.Four_Slide.Limits = [0 1];
            app.Four_Slide.MajorTicks = [];
            app.Four_Slide.ValueChangedFcn = createCallbackFcn(app, @Four_Slide_Callback, true);
            app.Four_Slide.MinorTicks = [];
            app.Four_Pop = uidropdown(app.Four);
            app.Four_Pop.Items = {'Visualization Off', 'Time/Frequency/Correlation'};
            app.Four_Pop.ValueChangedFcn = createCallbackFcn(app, @Four_Pop_Callback, true);
            app.Four_Pop.Value = 'Time/Frequency/Correlation';
            app.Group123 = uipanel(app.Fig);
            app.One = uibuttongroup(app.Group123);
            app.One_List = uilistbox(app.One);
            app.One_List.Items = {};
            app.One_List.ValueChangedFcn = createCallbackFcn(app, @One_List_Callback, true);
            app.One_List.Value = {};
            app.One_Pop = uidropdown(app.One);
            app.One_Pop.Items = {'Recording Set', 'All', 'Training', 'Testing'};
            app.One_Pop.ValueChangedFcn = createCallbackFcn(app, @One_Pop_Callback, true);
            app.One_Pop.Value = 'All';
            app.Two = uibuttongroup(app.Group123);
            app.Two_List = uilistbox(app.Two);
            app.Two_List.Items = {};
            app.Two_List.ValueChangedFcn = createCallbackFcn(app, @Two_List_Callback, true);
            app.Two_List.Value = {};
            app.Two_Slide = uislider(app.Two);
            app.Two_Slide.Limits = [0 1];
            app.Two_Slide.MajorTicks = [];
            app.Two_Slide.ValueChangedFcn = createCallbackFcn(app, @Two_Slide_Callback, true);
            app.Two_Slide.MinorTicks = [];
            app.Two_Pop = uidropdown(app.Two);
            app.Two_Pop.Items = {'Microphones', 'Annotations', 'Info'};
            app.Two_Pop.ValueChangedFcn = createCallbackFcn(app, @Two_Pop_Callback, true);
            app.Two_Pop.Value = 'Microphones';
            app.GroupPlay = uipanel(app.Fig);
            app.Previous = uibutton(app.GroupPlay, 'push');
            app.Previous.ButtonPushedFcn = createCallbackFcn(app, @Previous_Callback, true);
            app.Previous.Icon = 'Previous_image.png';
            app.Previous.Text = '';
            app.Back = uibutton(app.GroupPlay, 'push');
            app.Back.ButtonPushedFcn = createCallbackFcn(app, @Back_Callback, true);
            app.Back.Icon = 'Back_image.png';
            app.Back.Text = '';
            app.Clear = uibutton(app.GroupPlay, 'push');
            app.Clear.ButtonPushedFcn = createCallbackFcn(app, @Clear_Callback, true);
            app.Clear.Icon = 'Clear_image.png';
            app.Clear.Text = '';
            app.Reset = uibutton(app.GroupPlay, 'push');
            app.Reset.ButtonPushedFcn = createCallbackFcn(app, @Reset_Callback, true);
            app.Reset.Icon = 'Reset_image.png';
            app.Reset.Text = '';
            app.Skip = uibutton(app.GroupPlay, 'push');
            app.Skip.ButtonPushedFcn = createCallbackFcn(app, @Skip_Callback, true);
            app.Skip.Icon = 'Skip_image.png';
            app.Skip.Text = '';
            app.Next = uibutton(app.GroupPlay, 'push');
            app.Next.ButtonPushedFcn = createCallbackFcn(app, @Next_Callback, true);
            app.Next.Icon = 'Next_image.png';
            app.Next.Text = '';
            app.QueueMode = uibutton(app.GroupPlay, 'state');
            app.QueueMode.ValueChangedFcn = createCallbackFcn(app, @QueueMode_Callback, true);
            app.QueueMode.Enable = 'off';
            app.QueueMode.Icon = 'QueueMode_image.png';
            app.QueueMode.Text = '';
            app.QueueMode.Value = true;
            app.PlayMode = uibutton(app.GroupPlay, 'push');
            app.PlayMode.ButtonPushedFcn = createCallbackFcn(app, @PlayMode_Callback, true);
            app.PlayMode.Icon = 'PlayMode_image.png';
            app.PlayMode.Text = '';
            app.Mode = uibutton(app.GroupPlay, 'push');
            app.Mode.ButtonPushedFcn = createCallbackFcn(app, @Mode_Callback, true);
            app.Mode.Icon = 'Mode_image.png';
            app.Mode.Text = '';
            app.Debug = uibutton(app.GroupPlay, 'push');
            app.Debug.ButtonPushedFcn = createCallbackFcn(app, @Debug_Callback, true);
            app.Debug.Icon = 'Debug_image.png';
            app.Debug.Enable = 'off';
            app.Debug.Text = '';
            app.Play = uibutton(app.GroupPlay, 'push');
            app.Play.ButtonPushedFcn = createCallbackFcn(app, @Play_Callback, true);
            app.Play.Icon = 'Play_image.png';
            app.Play.Text = '';
            app.Panel = uipanel(app.Fig);
        end
    end

    methods (Access = public)

        % Construct app
        function app = QuailKit_App

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Fig)

            % Execute the startup function
            runStartupFcn(app, @QuailKit_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Fig)
        end
    end
end